classdef cluster_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        CameraDropDown         matlab.ui.control.DropDown
        AutoclusterButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        ClustersLabel          matlab.ui.control.Label
        FinishButton           matlab.ui.control.Button
        AutoClusterTable       matlab.ui.control.Table
        TypeDropDown           matlab.ui.control.DropDown
        DrawpolygonButton      matlab.ui.control.Button
        NewmetronomeButton     matlab.ui.control.Button
        AddtometronomeButton   matlab.ui.control.Button
        RemovepolygonButton    matlab.ui.control.Button
        ClusterDropDown        matlab.ui.control.DropDown
        MetronomeDropDown      matlab.ui.control.DropDown
        RemovemetronomeButton  matlab.ui.control.Button
        FinishpolygonButton    matlab.ui.control.Button
    end


    properties (Access = public)
        analysis
        results
        curPolFig
        curPol
        curPolCam
    end

    methods (Access = private)
    
        function updateClusterView(app,camId,typeId)
            cla(app.UIAxes);
            switch typeId
                case 1
                    plotPoints(app,camId);
                case 2
                    plotAutoClusters(app,camId);
            end
        end
    
        function plotPoints(app,camId)
            clusterscam = app.results.clusters.cam(camId);
            plot(app.UIAxes,clusterscam.mPosXList,clusterscam.mPosYList,'x');
            hold(app.UIAxes,'on');
            if(clusterscam.hasPolCluster)
                plot(app.UIAxes,clusterscam.mPosXList(clusterscam.curPolCluster),clusterscam.mPosYList(clusterscam.curPolCluster),'o');
            end
            
        end
    
        function plotAutoClusters(app,camId)
            hold(app.UIAxes,'on');
            clList = app.analysis.results.clusters.autoClusters(camId).clList;
            clNum = numel(clList);
            for clId = 1:clNum
                plot(app.UIAxes,app.results.clusters.cam(camId).mPosXList(clList{clId}),app.results.clusters.cam(camId).mPosYList(clList{clId}),'x');
            end
            legend(app.UIAxes,cellstr([repmat('Cluster  ',clNum,1), num2str((1:clNum).')]));
        end
        
        function updateTypeDropDownlist(app)
            
            app.TypeDropDown.Items = {'Points'};
            app.TypeDropDown.ItemsData = 1;
            if(app.results.clusters.autoClustered)
                app.TypeDropDown.Items = [{'Auto'},app.TypeDropDown.Items];
                app.TypeDropDown.ItemsData = [2,app.TypeDropDown.ItemsData];
            end
        end
    
        function updateClusterDropDownlist(app,camId)
            app.ClusterDropDown.Items = cell(0);
            app.ClusterDropDown.ItemsData = [];
            if(app.results.clusters.cam(camId).hasPolCluster)
                app.ClusterDropDown.Items = {'Polygon'};
                app.ClusterDropDown.ItemsData = 0;
            end
            if(app.results.clusters.autoClustered)
                clList = app.results.clusters.autoClusters(camId).clList;
                clNum = numel(clList);
                for clId = 1:clNum
                    app.ClusterDropDown.Items = [{['Auto ' num2str(clId)]},app.ClusterDropDown.Items];
                    app.ClusterDropDown.ItemsData = [clId,app.ClusterDropDown.ItemsData];
                end
            end
            
        end
    
        function updateMetronomeTable(app)
            mets= app.results.metronomes;
            if(~(numel(mets)==0))
                app.UITable.Data = [(1:numel(mets)).' [mets.nPoints].' [mets.nFramesNot].' [mets.nFramesSingle].' [mets.nFramesMulti].'];
                app.MetronomeDropDown.Items = cellstr([repmat('Metronome  ',numel(mets),1), num2str((1:numel(mets)).')]);
                app.MetronomeDropDown.ItemsData = (1:numel(mets)).';
            else
                app.UITable.Data = [];
                app.MetronomeDropDown.Items = cell(0);
                app.MetronomeDropDown.ItemsData = [];
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, analysis)
            app.analysis = analysis;
            app.analysis.initClustering();
            app.results = app.analysis.results;
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            camStr = cellstr([repmat('Camera ',app.analysis.metaData.nCams,1) num2str([1:app.analysis.metaData.nCams].')]);
            app.CameraDropDown.Items = camStr;
            app.CameraDropDown.ItemsData = 1:app.analysis.metaData.nCams;
            app.CameraDropDown.Value = 1;
            app.TypeDropDown.Items = {'Points'};
            app.TypeDropDown.ItemsData = 1;
            app.TypeDropDown.Value = 1;
            
            updateClusterView(app,1,1);
            updateClusterDropDownlist(app,1)
            app.AutoClusterTable.Data = [(1:app.analysis.metaData.nCams).' 2*ones(app.analysis.metaData.nCams,1)];
            updateMetronomeTable(app);
            
            
        end

        % Callback function
        function FinishButtonPushed(app, event)
            %uiresume(app.UIFigure);
            delete(app);
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            
            updateClusterView(app,app.CameraDropDown.Value,app.TypeDropDown.Value);
            updateClusterDropDownlist(app,app.CameraDropDown.Value)
        end

        % Button pushed function: AutoclusterButton
        function AutoclusterButtonPushed(app, event)
            app.analysis.autoClusterPointsGrid(app.AutoClusterTable.Data(:,2));
            updateTypeDropDownlist(app);
            app.TypeDropDown.Value = 2;
            updateClusterView(app,app.CameraDropDown.Value,app.TypeDropDown.Value);
            updateClusterDropDownlist(app,app.CameraDropDown.Value)
            %updateConList(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed2(app, event)
            app.analysis.finishClustering();
            delete(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            updateClusterView(app,app.CameraDropDown.Value,app.TypeDropDown.Value);
            updateClusterDropDownlist(app,app.CameraDropDown.Value)
        end

        % Button pushed function: DrawpolygonButton
        function DrawpolygonButtonPushed(app, event)
            f = figure();
            app.curPolFig = f;
            camId = app.CameraDropDown.Value;
            app.curPolCam = camId;
            plot(app.results.clusters.cam(camId).mPosXList,app.results.clusters.cam(camId).mPosYList,'x');
            fcn = makeConstrainToRectFcn('impoly',get(gca,'XLim'),get(gca,'YLim'));
            h = impoly(gca,'Closed',true,'PositionConstraintFcn',fcn);
            app.curPol = h;
            h.Deletable = false;
            
        end

        % Button pushed function: FinishpolygonButton
        function FinishpolygonButtonPushed(app, event)
            f = app.curPolFig;
            h = app.curPol;
            camId = app.curPolCam;
            if(~isempty(f) && ~isempty(h) && ~isempty(camId))
                camPosList = app.results.clusters.cam(camId).mPosList;
                polPos = h.getPosition();
                inPolIds = inpolygon(camPosList(:,1),camPosList(:,2),polPos(:,1),polPos(:,2));
                app.results.clusters.cam(camId).curPolCluster = inPolIds;
                app.results.clusters.cam(camId).hasPolCluster = true;
            end
            close(f);
            updateClusterView(app,app.CameraDropDown.Value,app.TypeDropDown.Value);
            updateClusterDropDownlist(app,app.CameraDropDown.Value)
        end

        % Button pushed function: RemovepolygonButton
        function RemovepolygonButtonPushed(app, event)
            camId = app.CameraDropDown.Value;
            app.results.clusters.cam(camId).curPolCluster = [];
            app.results.clusters.cam(camId).hasPolCluster = false;
            updateClusterView(app,app.CameraDropDown.Value,app.TypeDropDown.Value);
            updateClusterDropDownlist(app,app.CameraDropDown.Value)
        end

        % Button pushed function: NewmetronomeButton
        function NewmetronomeButtonPushed(app, event)
            app.analysis.createNewMetronome();
            updateMetronomeTable(app);
        end

        % Button pushed function: AddtometronomeButton
        function AddtometronomeButtonPushed(app, event)
            metId = app.MetronomeDropDown.Value;
            camId = app.CameraDropDown.Value;
            clId = app.ClusterDropDown.Value;
            if(clId == 0)
                app.analysis.addClusterToMetronome(metId,app.results.clusters.cam(camId).curPolCluster,camId);
            else
                app.analysis.addClusterToMetronome(metId,app.results.clusters.autoClusters(camId).clList{clId},camId);
            end
            updateMetronomeTable(app);
        end

        % Button pushed function: RemovemetronomeButton
        function RemovemetronomeButtonPushed(app, event)
            metId = app.MetronomeDropDown.Value;
            app.analysis.removeMetronome(metId);
            updateMetronomeTable(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.AutoclusterButton = uibutton(app.UIFigure, 'push');
            app.AutoclusterButton.ButtonPushedFcn = createCallbackFcn(app, @AutoclusterButtonPushed, true);
            app.AutoclusterButton.Text = 'Auto cluster';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Metronome'; 'Points'; 'Frames none'; 'Frames single'; 'Frames multiple'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true false false];
            app.ClustersLabel = uilabel(app.UIFigure);
            app.ClustersLabel.Text = 'Clusters';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed2, true);
            app.FinishButton.Text = 'Finish';
            app.AutoClusterTable = uitable(app.UIFigure);
            app.AutoClusterTable.ColumnName = {'Cam'; 'N'};
            app.AutoClusterTable.RowName = {};
            app.AutoClusterTable.ColumnEditable = true;
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.DrawpolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawpolygonButton.Text = 'Draw polygon';
            app.NewmetronomeButton = uibutton(app.UIFigure, 'push');
            app.NewmetronomeButton.ButtonPushedFcn = createCallbackFcn(app, @NewmetronomeButtonPushed, true);
            app.NewmetronomeButton.Text = 'New metronome';
            app.AddtometronomeButton = uibutton(app.UIFigure, 'push');
            app.AddtometronomeButton.ButtonPushedFcn = createCallbackFcn(app, @AddtometronomeButtonPushed, true);
            app.AddtometronomeButton.Text = 'Add to metronome';
            app.RemovepolygonButton = uibutton(app.UIFigure, 'push');
            app.RemovepolygonButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepolygonButtonPushed, true);
            app.RemovepolygonButton.Text = 'Remove polygon';
            app.ClusterDropDown = uidropdown(app.UIFigure);
            app.MetronomeDropDown = uidropdown(app.UIFigure);
            app.RemovemetronomeButton = uibutton(app.UIFigure, 'push');
            app.RemovemetronomeButton.ButtonPushedFcn = createCallbackFcn(app, @RemovemetronomeButtonPushed, true);
            app.RemovemetronomeButton.Text = 'Remove metronome';
            app.FinishpolygonButton = uibutton(app.UIFigure, 'push');
            app.FinishpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @FinishpolygonButtonPushed, true);
            app.FinishpolygonButton.Text = 'Finish polygon';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cluster_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end