classdef main_app_2018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FilesTab                      matlab.ui.container.Tab
        VideoFilePanel                matlab.ui.container.Panel
        LoadVideoButton               matlab.ui.control.Button
        VideoPathEditField            matlab.ui.control.EditField
        FoundLampVideo                matlab.ui.control.Lamp
        FoundLampMeta                 matlab.ui.control.Lamp
        FoundLampImage                matlab.ui.control.Lamp
        FoundLampAnalysis             matlab.ui.control.Lamp
        CalibrationImageLabel         matlab.ui.control.Label
        MetaDataFileLabel             matlab.ui.control.Label
        AnalysisDataFileLabel         matlab.ui.control.Label
        VideoFileLabel                matlab.ui.control.Label
        VideoPropertiesTextArea       matlab.ui.control.TextArea
        CalImagePreview               matlab.ui.control.UIAxes
        FoundLampResults              matlab.ui.control.Lamp
        ResultsDataFileLabel          matlab.ui.control.Label
        CamerasPanel                  matlab.ui.container.Panel
        LoadParametersButton          matlab.ui.control.Button
        ParametersDropDown            matlab.ui.control.DropDown
        CameraDropDown                matlab.ui.control.DropDown
        SelectButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        FinishButton                  matlab.ui.control.Button
        CameraLabel_2                 matlab.ui.control.Label
        ParametersetLabel             matlab.ui.control.Label
        ThresholdingTab               matlab.ui.container.Tab
        ViewPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        CameraDropDownView            matlab.ui.control.DropDown
        TimeSliderLabel               matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        AdjustColorLimitsButton       matlab.ui.control.Button
        NextFrameButton               matlab.ui.control.Button
        AdjustBlobLimitsButton        matlab.ui.control.Button
        AdjustBlobLimitsPButton       matlab.ui.control.Button
        AdjustColorLimitsPButton      matlab.ui.control.Button
        ImageselectorButtonGroup      matlab.ui.container.ButtonGroup
        VideoButton                   matlab.ui.control.RadioButton
        CalibrationImageButton        matlab.ui.control.RadioButton
        CameraLabel                   matlab.ui.control.Label
        CalibrationPanel              matlab.ui.container.Panel
        CalibrateButton               matlab.ui.control.Button
        SaveMetaButton                matlab.ui.control.Button
        AnalysisTab                   matlab.ui.container.Tab
        AnalysisPanel                 matlab.ui.container.Panel
        GatherallpointsButton         matlab.ui.control.Button
        ProgressGaugeLabel            matlab.ui.control.Label
        ProgressGauge                 matlab.ui.control.LinearGauge
        FramesTextAreaLabel           matlab.ui.control.Label
        FramesTextArea                matlab.ui.control.TextArea
        SaveAnalysisButton            matlab.ui.control.Button
        ProcessDataTab                matlab.ui.container.Tab
        DataProcessingPanel           matlab.ui.container.Panel
        SolvePlatformRotationsButton  matlab.ui.control.Button
        CorrectforPlatformButton      matlab.ui.control.Button
        ProgressGaugeData             matlab.ui.control.LinearGauge
        FramesTextArea_2Label         matlab.ui.control.Label
        FramesTextAreaData            matlab.ui.control.TextArea
        DeprojectButton               matlab.ui.control.Button
        ClusterButton                 matlab.ui.control.Button
        SaveResultsButton             matlab.ui.control.Button
        ResolvesignalsButton          matlab.ui.control.Button
        ExportResultsButton           matlab.ui.control.Button
        DataViewPanel                 matlab.ui.container.Panel
        DataDropDown                  matlab.ui.control.DropDown
        UIAxesData                    matlab.ui.control.UIAxes
        CameraDropDownDataView        matlab.ui.control.DropDown
        FlipMetronomePanel            matlab.ui.container.Panel
        MetronomeDropDown             matlab.ui.control.DropDown
        FlipButton                    matlab.ui.control.Button
        DataLabel                     matlab.ui.control.Label
        CameraifapplLabel             matlab.ui.control.Label
        AnalyzePanel                  matlab.ui.container.Panel
        TssSliderLabel                matlab.ui.control.Label
        TssSlider                     matlab.ui.control.Slider
        AnalyzesignalsButton          matlab.ui.control.Button
        JobsTab                       matlab.ui.container.Tab
        StatusTextAreaLabel           matlab.ui.control.Label
        StatusTextArea                matlab.ui.control.TextArea
    end


    properties (Access = private)
        videoProcessor  VideoProcessor 
    end

    methods(Static)
        function setFoundLamp(lamp,status)
            % Sets lamp to red (0), green(1) or yellow(2)
            switch status
                case 0
                    lamp.Color = 'r';
                case 1
                    lamp.Color = 'g';
                case 2
                    lamp.Color = 'y';
                otherwise
                    error('Not a valid color for lamp')
            end
        end
    end
    
    methods (Access = private)
    
        function dispStatus(app,statusStr)
            app.StatusTextArea.Value = [{sprintf('- %s',statusStr)};app.StatusTextArea.Value];
        end
        
        function dispError(app,errorStr)
            app.StatusTextArea.Value = [{sprintf('- Error: %s',errorStr)};app.StatusTextArea.Value];
        end


        function updateVideoFile(app,vFile,vPath)
            vP = app.videoProcessor;
            vP.updateVideoFile(vFile,vPath);                         
            
            app.VideoPathEditField.Value = vP.getVideoFile;
            
            dispStatus(app,['User selected ', vP.vName]);
            
            app.setFoundLamp(app.FoundLampVideo,vP.existVideoFile());
            app.setFoundLamp(app.FoundLampImage,vP.existImageFile());
            app.setFoundLamp(app.FoundLampMeta,vP.existMetaFile());
            app.setFoundLamp(app.FoundLampAnalysis,vP.existAnalysisFile());
            app.setFoundLamp(app.FoundLampResults,vP.existResultsFile());
            app.CalImagePreview.Visible = 'on';
            imshow(vP.calImage,'Parent',app.CalImagePreview);
            
            v = vP.videoProperties;
            app.VideoPropertiesTextArea.Value =  ...
                [{sprintf('Duration: %g s \nWidth: %d px \nHeight: %d px\nFrameRate: %d /s\n',...
                v.Duration,v.Width,v.Height,v.FrameRate)};...
                {sprintf('Horizontal Cameras: %d \nVertical cameras: %d \nTotal number of cameras: %d',...
                v.nCamsHor,v.nCamsVer,v.nCams)}];
            
            app.TimeSlider.Value = vP.videoReader.CurrentTime;
            app.TimeSlider.Limits = [0 v.Duration - 1/v.FrameRate];
            
            app.CameraDropDown.Items = cellstr(num2str((1:v.nCams).'));
            app.CameraDropDown.ItemsData = 1:v.nCams;
            
            app.CameraDropDownView.Items = cellstr(num2str((1:v.nCams).'));
            app.CameraDropDownView.ItemsData = 1:v.nCams;
            
            app.CameraDropDownDataView.Items = cellstr(num2str((1:v.nCams).'));
            app.CameraDropDownDataView.ItemsData = 1:v.nCams;
            
            app.CamerasPanel.Visible = 'on';
            updateParDropDown(app);
            updateCamParTable(app);
            updateDataDropDownlist(app);
        end
        
        function loadCamPams(app,cFile,cPath)
            meta = app.videoProcessor.metaData;
            meta.loadCameraParameters(cFile,cPath);
            dispStatus(app,['User selected ', cFile]);
            updateParDropDown(app);
        end
        
        function updateParDropDown(app)
            meta = app.videoProcessor.metaData;
            if(meta.hasLoadedCameraParams)
                parNames = meta.getLoadedParameterNames();
                app.ParametersDropDown.Items = parNames;
                app.ParametersDropDown.ItemsData = 1:length(parNames);
            end
        end
        
        function updateCamParTable(app)
            meta = app.videoProcessor.metaData;
            app.UITable.Data = meta.getCamParTable();
        end
        
        function updateImage(app)
            
            
            image = getCurrentImage(app);
            imshow(image,'Parent',app.UIAxes);
            
        end
        
        function image = getCurrentImage(app)
            time = app.TimeSlider.Value;
            camId = app.CameraDropDownView.Value;
            if(app.VideoButton.Value)
                image = app.videoProcessor.getAnnotatedCamIm(time,camId);
            else
                image = app.videoProcessor.getAnnotatedCamCalIm(camId);
            end
        end


        function plotPositionRotationData(app)
            plot(app.UIAxesData,app.videoProcessor.analysis.results.phiStor*360/2/pi);
            hold(app.UIAxesData,'on');
            plot(app.UIAxesData,app.videoProcessor.analysis.results.tStor(:,1));
            plot(app.UIAxesData,app.videoProcessor.analysis.results.tStor(:,2));
            
            legend(app.UIAxesData,'Rotation','X-Position','Y-Position')
            hold(app.UIAxesData,'off');
            zoom(app.UIAxesData,'off');
        end
        
        function plotPointClouds(app)
            r = app.videoProcessor.analysis.results;
            camIdx = app.CameraDropDownDataView.Value;
            mPos = cell2mat(r.mPos(:,camIdx));
            mPosXList = mPos(:,1);
            mPosYList = mPos(:,2);
            
            
            plot(app.UIAxesData,mPosXList.',mPosYList.','x')
            legend(app.UIAxesData,'Detected points')
            
        end
        
        function plotPointCloudsCor(app)
            r = app.videoProcessor.analysis.results;
            camIdx = app.CameraDropDownDataView.Value;
            mPos = cell2mat(r.mPosCor(:,camIdx));
            mPosXList = mPos(:,1);
            mPosYList = mPos(:,2);
            
            
            plot(app.UIAxesData,mPosXList.',mPosYList.','x')
            legend(app.UIAxesData,'Corrected points')
        end
    
        function plotPointCloudsClustered(app)
            r = app.videoProcessor.analysis.results;
            nM = numel(r.metronomes);
            hold(app.UIAxesData,'off');
            for metId = 1:nM
                met = r.metronomes(metId);
                plot(app.UIAxesData,met.posXList.',met.posYList.','x')
                hold(app.UIAxesData,'on');
            end
            legend(app.UIAxesData,cellstr([repmat('Cluster  ',nM,1), num2str((1:nM).')]));
            
        end
        
        function plotPointCloudsDep(app)
            
            r = app.videoProcessor.analysis.results;
            nM = numel(r.metronomes);
            hold(app.UIAxesData,'on');
            ccM = lines(nM);
            h = [];
            for metId = 1:nM
                met = r.metronomes(metId);
                plot(app.UIAxesData,met.Mrpx.',met.Mrpy.','o','Color',ccM(metId,:))
                plot(app.UIAxesData,met.posXList.',met.posYList.','x','Color',ccM(metId,:))
                
                %plot(app.UIAxesData,met.Mx.',met.My.','Color',ccM(metId,:))
                metMinTheta = min(met.Mtheta);
                metMaxTheta = max(met.Mtheta);
                L = 70;
                metMinPos = L*sin(metMinTheta)*([cos(met.psi) sin(met.psi)])+met.centerPos.';
                
                metMaxPos = L*sin(metMaxTheta)*([cos(met.psi) sin(met.psi)])+met.centerPos.';
                metDPos = metMaxPos-metMinPos;
                hl = quiver(app.UIAxesData,metMinPos(1),metMinPos(2),metDPos(1),metDPos(2),0,'Color',ccM(metId,:));
                quiver(app.UIAxesData,metMinPos(1),metMinPos(2),metDPos(1),metDPos(2),0,'k');
                h = [h hl];
                plot(app.UIAxesData,met.camPositions(:,1).',met.camPositions(:,2).','*','Color',ccM(metId,:))
            end
            legend(app.UIAxesData,h,cellstr([repmat('Metronome  ',nM,1), num2str((1:nM).')]).');
                        
        end
    
        function plotResolved(app)
            
            r = app.videoProcessor.analysis.results;
            nM = numel(r.metronomes);
            hold(app.UIAxesData,'on');
            ccM = lines(nM);
            for metId = 1:nM
                met = r.signals.met(metId);
                plot(app.UIAxesData,r.signals.t,met.theta_f,'Color',ccM(metId,:))
            end
            legend(app.UIAxesData,cellstr([repmat('Metronome  ',nM,1), num2str((1:nM).')]).');
                        
        end
        
        function updateDataDropDownlist(app)
            results = app.videoProcessor.analysis.results;
            app.DataDropDown.Items = cell(0);
            app.DataDropDown.ItemsData = [];
            
            
            if(results.pointsGathered)
                app.DataDropDown.Items = [{'Marker Points'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [1,app.DataDropDown.ItemsData];
            end
            if(results.solvedMovement)
                app.DataDropDown.Items = [{'Position and rotation'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [2,app.DataDropDown.ItemsData];
            end
            if(results.pointsCorrected)
                app.DataDropDown.Items = [{'Corrected Point Clouds'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [3,app.DataDropDown.ItemsData];
            end
            if(results.clustered)
                app.DataDropDown.Items = [{'Clustered points'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [4,app.DataDropDown.ItemsData];
            end
            if(results.deprojected)
                app.DataDropDown.Items = [{'Deprojected points'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [5,app.DataDropDown.ItemsData];
                
            end
            if(results.resolved)
                app.DataDropDown.Items = [{'Resolved signals'},app.DataDropDown.Items];
                app.DataDropDown.ItemsData = [6,app.DataDropDown.ItemsData];
            end
        end
        
        function enableFlipMetronome(app)
            app.FlipMetronomePanel.Visible = 'on';
            r = app.videoProcessor.analysis.results;
            if(r.deprojected)
                nM = numel(r.metronomes);
                app.MetronomeDropDown.Items = cell(0);
                app.MetronomeDropDown.ItemsData = [];
                for metId = 1:nM                
                    app.MetronomeDropDown.Items = [{['Metronome ' num2str(metId)]},app.MetronomeDropDown.Items];
                    app.MetronomeDropDown.ItemsData = [metId,app.MetronomeDropDown.ItemsData];
                end
            end
        end
    
        function enableAnalyze(app)
            app.AnalyzePanel.Visible = 'on';
            r = app.videoProcessor.analysis.results;
            app.TssSlider.Limits = [min(r.signals.t) max(r.signals.t)];
        end
        
        function updateDataView(app)
            cla(app.UIAxesData);
            value = app.DataDropDown.Value;
            app.FlipMetronomePanel.Visible = 'off';
            app.AnalyzePanel.Visible = 'off';
            switch value
                case 1
                    plotPointClouds(app);
                case 2
                    plotPositionRotationData(app);
                case 3
                    plotPointCloudsCor(app);
                case 4
                    plotPointCloudsClustered(app);
                case 5
                    enableFlipMetronome(app);
                    plotPointCloudsDep(app);
                case 6
                    enableAnalyze(app);
                    plotResolved(app)
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CamerasPanel.Visible = 'off';
            app.FlipMetronomePanel.Visible = 'off';
            app.AnalyzePanel.Visible = 'off';
            app.videoProcessor = VideoProcessor();
            
            % Remove title, axis labels, and tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            warning('off','vision:calibrate:boardShouldBeAsymmetric')


            %app.ViewPanel.Visible = 'off';
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            [vFile,vPath] = uigetfile( ...
                {'*.avi;*.mp4','Video Files (*.avi;*.mp4)';...
                 '*.*',  'All Files (*.*)'}, ...
                 'Pick a video file',app.videoProcessor.getVideoFile());
            if isequal(vFile,0)
                dispStatus(app,'User selected Cancel');
            else
                
                updateVideoFile(app,vFile,vPath);
            end
            
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            [cFile,cPath] = uigetfile({'*.mat','Mat Files (*.mat)'},'Pick a data file');
            if isequal(cFile,0)
                dispStatus(app,'User selected Cancel');
            else
                loadCamPams(app,cFile,cPath);
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            cameraNum = app.CameraDropDown.Value;
            parNum = app.ParametersDropDown.Value;
            if((~isempty(cameraNum)) && (~isempty(parNum)))
                meta = app.videoProcessor.metaData;
                meta.selectCameraParameters(cameraNum,parNum);
                dispStatus(app,['User selected a parameter set for camera ', num2str(cameraNum)]);
            end
            updateCamParTable(app);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            
            updateImage(app);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            meta = app.videoProcessor.metaData;
            
            X = meta.calibration.X;
            constraints = meta.calibration.constraints;
            constraintsStr = meta.calibration.constraintsStr;
            
            disp('Starting Match app')
            guiHandle = testMatchapp(meta.calImage,meta,constraints,constraintsStr,X);
            
            while(guiHandle.isvalid)
                X = guiHandle.X;
                constraints = guiHandle.constraints;
                constraintsStr = guiHandle.constraintsStr;
                pause(0.01);
            end
            
            cal.X = X;
            cal.constraints = constraints;
            cal.constraintsStr = constraintsStr;
            meta.setNewCalibration(cal);
            dispStatus(app,'Calibration performed and world constructed. Metadata finished, please save!');
        end

        % Button pushed function: SaveMetaButton
        function SaveMetaButtonPushed(app, event)
            app.videoProcessor.saveMetaData();
            dispStatus(app,'Metadata saved!');
        end

        % Button pushed function: GatherallpointsButton
        function GatherallpointsButtonPushed(app, event)
            t1 = tic;
            v = app.videoProcessor.videoReader;
            a = app.videoProcessor.analysis;
            a.prepareAnalysis();
            while(v.hasFrame())
                a.stepAnalysis();
                if(mod(a.curFrame,1)==0)
                    app.FramesTextArea.Value = {sprintf('Number of Frames: %d \nCurrent Frame: %d\nEstimated time left: %d m',...
                        a.nFrames,a.curFrame,round(toc(t1)/a.curFrame*(a.nFrames-a.curFrame)/60))};
                    app.ProgressGauge.Value = 100*a.curFrame/a.nFrames;
                    drawnow;
                end
            end
            a.finishAnalysis();
            dispStatus(app,'Analysis of frames performed. Raw analysis finished, please save!');
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            app.videoProcessor.saveAnalysis();
            app.videoProcessor.saveResults();
            dispStatus(app,'Analysis and results saved!');
        end

        % Value changed function: CameraDropDownView
        function CameraDropDownViewValueChanged(app, event)
            updateImage(app);                        
        end

        % Button pushed function: AdjustColorLimitsButton
        function AdjustColorLimitsButtonPushed(app, event)
            camId = app.CameraDropDownView.Value;
            meta = app.videoProcessor.metaData;
            
            camIm = getCurrentImage(app);
            colLims = meta.cam(camId).colLims;
            
            guiHandle = testColorapp(camIm,colLims);
            while(guiHandle.isvalid)
                colLims = guiHandle.colLimsCur;
                pause(0.01);
            end
                        
            meta.setNewColLims(camId,colLims);
            dispStatus(app,'New metronome color thresholding parameters selected');
        end

        % Button pushed function: AdjustBlobLimitsButton
        function AdjustBlobLimitsButtonPushed(app, event)
            camId = app.CameraDropDownView.Value;
            meta = app.videoProcessor.metaData;
            
            camIm = getCurrentImage(app);
            colLims = meta.cam(camId).colLims;
            blobLims = meta.cam(camId).blobLims;
            
            guiHandle = testBlobapp(camIm,colLims,blobLims);
            while(guiHandle.isvalid)
                blobLims = guiHandle.blobLimsCur;
                pause(0.01);
            end
                        
            meta.setNewBlobLims(camId,blobLims);
            dispStatus(app,'New metronome blob size parameters selected');
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            app.TimeSlider.Value = app.videoProcessor.getCurrentTime;
            updateImage(app);
        end

        % Button pushed function: SolvePlatformRotationsButton
        function SolvePlatformRotationsButtonPushed(app, event)
            app.videoProcessor.analysis.solvePlatformMovements();

            
            updateDataDropDownlist(app);
            
            updateDataView(app);
            dispStatus(app,'Platform movement solved for. Please save!');
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            
            updateDataView(app);
        end

        % Button pushed function: CorrectforPlatformButton
        function CorrectforPlatformButtonPushed(app, event)
            app.videoProcessor.analysis.correctForPlatformMovements();
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,'Metronome blobs corrected for platform movement. Please save!');
        end

        % Button pushed function: DeprojectButton
        function DeprojectButtonPushed(app, event)
            app.videoProcessor.analysis.deprojectAllMetronomes();
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,'Metronome deprojected from cameras. Please save!');
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            cluster_app(app.videoProcessor.analysis);
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,'Metronome blobs clustered. Please save!');
        end

        % Button pushed function: AdjustBlobLimitsPButton
        function AdjustBlobLimitsPButtonPushed(app, event)
            camId = app.CameraDropDownView.Value;
            meta = app.videoProcessor.metaData;
            
            camIm = getCurrentImage(app);
            pcolLims = meta.cam(camId).pcolLims;
            pblobLims = meta.cam(camId).pblobLims;
            
            guiHandle = testBlobapp(camIm,pcolLims,pblobLims);
            while(guiHandle.isvalid)
                pblobLims = guiHandle.blobLimsCur;
                pause(0.01);
            end
                        
            meta.setNewPBlobLims(camId,pblobLims);
            dispStatus(app,'New platform blob size parameters selected');
        end

        % Button pushed function: AdjustColorLimitsPButton
        function AdjustColorLimitsPButtonPushed(app, event)
            camId = app.CameraDropDownView.Value;
            meta = app.videoProcessor.metaData;
            
            camIm = getCurrentImage(app);
            pcolLims = meta.cam(camId).pcolLims;
            
            guiHandle = testColorapp(camIm,pcolLims);
            while(guiHandle.isvalid)
                pcolLims = guiHandle.colLimsCur;
                pause(0.01);
            end
                        
            meta.setNewPColLims(camId,pcolLims);
            dispStatus(app,'New platform color thresholding parameters selected');
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.videoProcessor.metaData.finishCamParameters();
            dispStatus(app,'Finished Cameras');
            updateImage(app);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            app.videoProcessor.saveAnalysis();
            app.videoProcessor.saveResults();
            dispStatus(app,'Analysis and results saved!');
        end

        % Value changed function: CameraDropDownDataView
        function CameraDropDownDataViewValueChanged(app, event)
            updateDataDropDownlist(app);
            updateDataView(app);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            a = app.videoProcessor.analysis;
            a.flipMetronome(app.MetronomeDropDown.Value);
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,['Metronome ' num2str(app.MetronomeDropDown.Value) ' flipped. Please save!']);
        end

        % Button pushed function: ResolvesignalsButton
        function ResolvesignalsButtonPushed(app, event)
            a = app.videoProcessor.analysis;
            a.resolveSignals(app.videoProcessor.logData.timeVector);
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,'Metronome signals resolved and constructed. Please save!');
        end

        % Button pushed function: AnalyzesignalsButton
        function AnalyzesignalsButtonPushed(app, event)
            a = app.videoProcessor.analysis;
            a.analyzeSignals(app.TssSlider.Value);
            updateDataDropDownlist(app);
            updateDataView(app);
            dispStatus(app,'Analysis of signals finished. You are finished, please save!');
        end

        % Selection changed function: ImageselectorButtonGroup
        function ImageselectorButtonGroupSelectionChanged(app, event)
            
            updateImage(app);
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            assignin('base','Results',app.videoProcessor.analysis.results);
            dispStatus(app,'Results exported to workspace!');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.VideoFilePanel = uipanel(app.FilesTab);
            app.VideoFilePanel.Title = 'Video File';
            app.LoadVideoButton = uibutton(app.VideoFilePanel, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.VideoPathEditField = uieditfield(app.VideoFilePanel, 'text');
            app.FoundLampVideo = uilamp(app.VideoFilePanel);
            app.FoundLampMeta = uilamp(app.VideoFilePanel);
            app.FoundLampImage = uilamp(app.VideoFilePanel);
            app.FoundLampAnalysis = uilamp(app.VideoFilePanel);
            app.CalibrationImageLabel = uilabel(app.VideoFilePanel);
            app.CalibrationImageLabel.Text = 'Calibration Image';
            app.MetaDataFileLabel = uilabel(app.VideoFilePanel);
            app.MetaDataFileLabel.Text = 'MetaData File';
            app.AnalysisDataFileLabel = uilabel(app.VideoFilePanel);
            app.AnalysisDataFileLabel.Text = 'Analysis Data File';
            app.VideoFileLabel = uilabel(app.VideoFilePanel);
            app.VideoFileLabel.Text = 'Video File';
            app.VideoPropertiesTextArea = uitextarea(app.VideoFilePanel);
            app.VideoPropertiesTextArea.Editable = 'off';
            app.CalImagePreview = uiaxes(app.VideoFilePanel);
            app.CalImagePreview.Visible = 'off';
            app.FoundLampResults = uilamp(app.VideoFilePanel);
            app.ResultsDataFileLabel = uilabel(app.VideoFilePanel);
            app.ResultsDataFileLabel.Text = 'Results Data File';
            app.CamerasPanel = uipanel(app.FilesTab);
            app.CamerasPanel.Title = 'Cameras';
            app.LoadParametersButton = uibutton(app.CamerasPanel, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ParametersDropDown = uidropdown(app.CamerasPanel);
            app.ParametersDropDown.Items = {};
            app.ParametersDropDown.Value = {};
            app.CameraDropDown = uidropdown(app.CamerasPanel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.Value = {};
            app.SelectButton = uibutton(app.CamerasPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.UITable = uitable(app.CamerasPanel);
            app.UITable.ColumnName = {'Camera'; 'Parameter'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.CamerasPanel);
            app.Label.Text = '';
            app.FinishButton = uibutton(app.CamerasPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CameraLabel_2 = uilabel(app.CamerasPanel);
            app.CameraLabel_2.Text = 'Camera';
            app.ParametersetLabel = uilabel(app.CamerasPanel);
            app.ParametersetLabel.Text = 'Parameter set';
            app.ThresholdingTab = uitab(app.TabGroup);
            app.ThresholdingTab.Title = 'Thresholding';
            app.ViewPanel = uipanel(app.ThresholdingTab);
            app.ViewPanel.Title = 'View';
            app.UIAxes = uiaxes(app.ViewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CameraDropDownView = uidropdown(app.ViewPanel);
            app.CameraDropDownView.Items = {};
            app.CameraDropDownView.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownViewValueChanged, true);
            app.CameraDropDownView.Value = {};
            app.TimeSliderLabel = uilabel(app.ViewPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ViewPanel);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.AdjustColorLimitsButton = uibutton(app.ViewPanel, 'push');
            app.AdjustColorLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustColorLimitsButtonPushed, true);
            app.AdjustColorLimitsButton.Text = 'Adjust Color Limits';
            app.NextFrameButton = uibutton(app.ViewPanel, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.AdjustBlobLimitsButton = uibutton(app.ViewPanel, 'push');
            app.AdjustBlobLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustBlobLimitsButtonPushed, true);
            app.AdjustBlobLimitsButton.Text = 'Adjust Blob Limits';
            app.AdjustBlobLimitsPButton = uibutton(app.ViewPanel, 'push');
            app.AdjustBlobLimitsPButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustBlobLimitsPButtonPushed, true);
            app.AdjustBlobLimitsPButton.Text = 'Adjust Blob Limits P';
            app.AdjustColorLimitsPButton = uibutton(app.ViewPanel, 'push');
            app.AdjustColorLimitsPButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustColorLimitsPButtonPushed, true);
            app.AdjustColorLimitsPButton.Text = 'Adjust Color Limits P';
            app.ImageselectorButtonGroup = uibuttongroup(app.ViewPanel);
            app.ImageselectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageselectorButtonGroupSelectionChanged, true);
            app.ImageselectorButtonGroup.Title = 'Image selector';
            app.VideoButton = uiradiobutton(app.ImageselectorButtonGroup);
            app.VideoButton.Text = 'Video';
            app.CalibrationImageButton = uiradiobutton(app.ImageselectorButtonGroup);
            app.CalibrationImageButton.Text = 'Calibration Image';
            app.CalibrationImageButton.Value = true;
            app.CameraLabel = uilabel(app.ViewPanel);
            app.CameraLabel.Text = 'Camera';
            app.CalibrationPanel = uipanel(app.ThresholdingTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SaveMetaButton = uibutton(app.CalibrationPanel, 'push');
            app.SaveMetaButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMetaButtonPushed, true);
            app.SaveMetaButton.Text = 'Save Meta';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisPanel = uipanel(app.AnalysisTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.GatherallpointsButton = uibutton(app.AnalysisPanel, 'push');
            app.GatherallpointsButton.ButtonPushedFcn = createCallbackFcn(app, @GatherallpointsButtonPushed, true);
            app.GatherallpointsButton.Text = 'Gather all points';
            app.ProgressGaugeLabel = uilabel(app.AnalysisPanel);
            app.ProgressGaugeLabel.Text = 'Progress %';
            app.ProgressGauge = uigauge(app.AnalysisPanel, 'linear');
            app.FramesTextAreaLabel = uilabel(app.AnalysisPanel);
            app.FramesTextAreaLabel.Text = 'Frames';
            app.FramesTextArea = uitextarea(app.AnalysisPanel);
            app.SaveAnalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.ProcessDataTab = uitab(app.TabGroup);
            app.ProcessDataTab.Title = 'Process Data';
            app.DataProcessingPanel = uipanel(app.ProcessDataTab);
            app.DataProcessingPanel.Title = 'Data Processing';
            app.SolvePlatformRotationsButton = uibutton(app.DataProcessingPanel, 'push');
            app.SolvePlatformRotationsButton.ButtonPushedFcn = createCallbackFcn(app, @SolvePlatformRotationsButtonPushed, true);
            app.SolvePlatformRotationsButton.Text = 'Solve Platform Rotations';
            app.CorrectforPlatformButton = uibutton(app.DataProcessingPanel, 'push');
            app.CorrectforPlatformButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectforPlatformButtonPushed, true);
            app.CorrectforPlatformButton.Text = 'Correct for Platform';
            app.ProgressGaugeData = uigauge(app.DataProcessingPanel, 'linear');
            app.FramesTextArea_2Label = uilabel(app.DataProcessingPanel);
            app.FramesTextArea_2Label.Text = 'Frames';
            app.FramesTextAreaData = uitextarea(app.DataProcessingPanel);
            app.DeprojectButton = uibutton(app.DataProcessingPanel, 'push');
            app.DeprojectButton.ButtonPushedFcn = createCallbackFcn(app, @DeprojectButtonPushed, true);
            app.DeprojectButton.Text = 'Deproject';
            app.ClusterButton = uibutton(app.DataProcessingPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            app.SaveResultsButton = uibutton(app.DataProcessingPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.ResolvesignalsButton = uibutton(app.DataProcessingPanel, 'push');
            app.ResolvesignalsButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvesignalsButtonPushed, true);
            app.ResolvesignalsButton.Text = 'Resolve signals';
            app.ExportResultsButton = uibutton(app.DataProcessingPanel, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.DataViewPanel = uipanel(app.ProcessDataTab);
            app.DataViewPanel.Title = 'Data View';
            app.DataDropDown = uidropdown(app.DataViewPanel);
            app.DataDropDown.Items = {};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = {};
            app.UIAxesData = uiaxes(app.DataViewPanel);
            title(app.UIAxesData, 'Title')
            xlabel(app.UIAxesData, 'X')
            ylabel(app.UIAxesData, 'Y')
            app.CameraDropDownDataView = uidropdown(app.DataViewPanel);
            app.CameraDropDownDataView.Items = {};
            app.CameraDropDownDataView.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownDataViewValueChanged, true);
            app.CameraDropDownDataView.Value = {};
            app.FlipMetronomePanel = uipanel(app.DataViewPanel);
            app.FlipMetronomePanel.Title = 'Flip Metronome';
            app.MetronomeDropDown = uidropdown(app.FlipMetronomePanel);
            app.MetronomeDropDown.Items = {};
            app.MetronomeDropDown.Value = {};
            app.FlipButton = uibutton(app.FlipMetronomePanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.DataLabel = uilabel(app.DataViewPanel);
            app.DataLabel.Text = 'Data';
            app.CameraifapplLabel = uilabel(app.DataViewPanel);
            app.CameraifapplLabel.Text = 'Camera (if appl.)';
            app.AnalyzePanel = uipanel(app.ProcessDataTab);
            app.AnalyzePanel.Title = 'Analyze';
            app.TssSliderLabel = uilabel(app.AnalyzePanel);
            app.TssSliderLabel.Text = 'Tss';
            app.TssSlider = uislider(app.AnalyzePanel);
            app.AnalyzesignalsButton = uibutton(app.AnalyzePanel, 'push');
            app.AnalyzesignalsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzesignalsButtonPushed, true);
            app.AnalyzesignalsButton.Text = 'Analyze signals';
            app.JobsTab = uitab(app.TabGroup);
            app.JobsTab.Title = 'Jobs';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app_2018

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end