classdef testBlobapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        ArMinLabel         matlab.ui.control.Label
        ArMinSlider        matlab.ui.control.Slider
        ArMaxLabel         matlab.ui.control.Label
        ArMaxSlider        matlab.ui.control.Slider
        PiMinLabel         matlab.ui.control.Label
        PiMinSlider        matlab.ui.control.Slider
        PiMaxLabel         matlab.ui.control.Label
        PiMaxSlider        matlab.ui.control.Slider
        MajMinLabel        matlab.ui.control.Label
        MajMinSlider       matlab.ui.control.Slider
        MajMaxLabel        matlab.ui.control.Label
        MajMaxSlider       matlab.ui.control.Slider
        ResetArMinButton   matlab.ui.control.Button
        ResetArMaxButton   matlab.ui.control.Button
        ResetPiMinButton   matlab.ui.control.Button
        ResetPiMaxButton   matlab.ui.control.Button
        ResetMajMinButton  matlab.ui.control.Button
        ResetMajMaxButton  matlab.ui.control.Button
        ResetAllButton     matlab.ui.control.Button
        FinishButton       matlab.ui.control.Button
        ArMinEditField     matlab.ui.control.NumericEditField
        ArMaxEditField     matlab.ui.control.NumericEditField
        PiMinEditField     matlab.ui.control.NumericEditField
        PiMaxEditField     matlab.ui.control.NumericEditField
        MajMinEditField    matlab.ui.control.NumericEditField
        MajMaxEditField    matlab.ui.control.NumericEditField
        UITable            matlab.ui.control.Table
    end


    properties (Access = public)
        blobLimsCur   % Current blobLims
        blobLimsOr    % Original blobLims
        colLims       % ColLims
        image         % Image 
    end

    methods (Access = private)
    
        function updateImage(app)
            [blobs,markedIm] = findMarkers(app.image,app.colLims,app.blobLimsCur);
            if(blobs(1).n>0)
                app.UITable.Data = [round([[blobs.id].' [blobs.area].' [blobs.x].' [blobs.y].']) [blobs.pi].' round([blobs.major].')];
            else
                app.UITable.Data = [[] [] [] [] [] []];
            end
            app.UITable.ColumnName = {'ID';'Area';'X';'Y';'Pi';'Major'};
            app.UITable.ColumnWidth = {75, 75, 75, 75, 75, 75};
            % Show Image
            imshow(markedIm,'Parent',app.UIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, colLims, blobLims)
            app.blobLimsCur = blobLims;
            app.blobLimsOr = blobLims;
            app.colLims = colLims;
            app.image = image;
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Set sliders
            app.ArMinSlider.Value = blobLims(1);
            app.ArMaxSlider.Value = blobLims(2);
            app.PiMinSlider.Value = blobLims(3);
            app.PiMaxSlider.Value = blobLims(4);
            app.MajMinSlider.Value = blobLims(5);
            app.MajMaxSlider.Value = blobLims(6);
            
            app.ArMinEditField.Value = blobLims(1);
            app.ArMaxEditField.Value = blobLims(2);
            app.PiMinEditField.Value = blobLims(3);
            app.PiMaxEditField.Value = blobLims(4);
            app.MajMinEditField.Value = blobLims(5);
            app.MajMaxEditField.Value = blobLims(6);
            
            updateImage(app);
            
        end

        % Value changed function: ArMinSlider
        function ArMinSliderValueChanged(app, event)
            value = app.ArMinSlider.Value;
            app.ArMinEditField.Value = value;
            app.blobLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: PiMinSlider
        function PiMinSliderValueChanged(app, event)
            value = app.PiMinSlider.Value;
            app.PiMinEditField.Value = value;
            app.blobLimsCur(3) = value;
            updateImage(app);
        end

        % Value changing function: PiMinSlider
        function PiMinSliderValueChanging(app, event)
            value = event.Value;
            app.PiMinEditField.Value = value;
            app.blobLimsCur(3) = value;
            updateImage(app);
        end

        % Value changing function: ArMinSlider
        function ArMinSliderValueChanging(app, event)
            value = event.Value;
            app.ArMinEditField.Value = value;
            app.blobLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: ArMaxSlider
        function ArMaxSliderValueChanged(app, event)
            value = app.ArMaxSlider.Value;
            app.ArMaxEditField.Value = value;
            app.blobLimsCur(2) = value;
            updateImage(app);
        end

        % Value changing function: ArMaxSlider
        function ArMaxSliderValueChanging(app, event)
            value = event.Value;
            app.ArMaxEditField.Value = value;
            app.blobLimsCur(2) = value;
            updateImage(app);
        end

        % Value changed function: PiMaxSlider
        function PiMaxSliderValueChanged(app, event)
            value = app.PiMaxSlider.Value;
            app.PiMaxEditField.Value = value;
            app.blobLimsCur(4) = value;
            updateImage(app);
        end

        % Value changing function: PiMaxSlider
        function PiMaxSliderValueChanging(app, event)
            value = event.Value;
            app.PiMaxEditField.Value = value;
            app.blobLimsCur(4) = value;
            updateImage(app);
        end

        % Value changed function: MajMinSlider
        function MajMinSliderValueChanged(app, event)
            value = app.MajMinSlider.Value;
            app.MajMinEditField.Value = value;
            app.blobLimsCur(5) = value;
            updateImage(app);
        end

        % Value changing function: MajMinSlider
        function MajMinSliderValueChanging(app, event)
            value = event.Value;
            app.MajMinEditField.Value = value;
            app.blobLimsCur(5) = value;
            updateImage(app);
        end

        % Value changed function: MajMaxSlider
        function MajMaxSliderValueChanged(app, event)
            value = app.MajMaxSlider.Value;
            app.MajMaxEditField.Value = value;
            app.blobLimsCur(6) = value;
            updateImage(app);
        end

        % Value changing function: MajMaxSlider
        function MajMaxSliderValueChanging(app, event)
            value = event.Value;
            app.MajMaxEditField.Value = value;
            app.blobLimsCur(6) = value;
            updateImage(app);
        end

        % Button pushed function: ResetArMinButton
        function ResetArMinButtonPushed(app, event)
            value = app.blobLimsOr(1);
            app.blobLimsCur(1) = value;
            app.ArMinSlider.Value = value;
            app.ArMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetArMaxButton
        function ResetArMaxButtonPushed(app, event)
            value = app.blobLimsOr(2);
            app.blobLimsCur(2) = value;
            app.ArMaxSlider.Value = value;
            app.ArMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetPiMinButton
        function ResetPiMinButtonPushed(app, event)
            value = app.blobLimsOr(3);
            app.blobLimsCur(3) = value;
            app.PiMinSlider.Value = value;
            app.PiMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetPiMaxButton
        function ResetPiMaxButtonPushed(app, event)
            value = app.blobLimsOr(4);
            app.blobLimsCur(4) = value;
            app.PiMaxSlider.Value = value;
            app.PiMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetMajMinButton
        function ResetMajMinButtonPushed(app, event)
            value = app.blobLimsOr(5);
            app.blobLimsCur(5) = value;
            app.MajMinSlider.Value = value;
            app.MajMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetMajMaxButton
        function ResetMajMaxButtonPushed(app, event)
            value = app.blobLimsOr(6);
            app.blobLimsCur(6) = value;
            app.MajMaxSlider.Value = value;
            app.MajMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.blobLimsCur = app.blobLimsOr;
            app.ArMinSlider.Value = app.blobLimsCur(1);
            app.ArMinEditField.Value = app.blobLimsCur(1);
            app.ArMaxSlider.Value = app.blobLimsCur(2);
            app.ArMaxEditField.Value = app.blobLimsCur(2);
            
            app.PiMinSlider.Value = app.blobLimsCur(3);
            app.PiMinEditField.Value = app.blobLimsCur(3);
            app.PiMaxSlider.Value = app.blobLimsCur(4);
            app.PiMaxEditField.Value = app.blobLimsCur(4);
            
            app.MajMinSlider.Value = app.blobLimsCur(5);
            app.MajMinEditField.Value = app.blobLimsCur(5);
            app.MajMaxSlider.Value = app.blobLimsCur(6);
            app.MajMaxEditField.Value = app.blobLimsCur(6);
            updateImage(app);
        end

        % Value changed function: ArMinEditField
        function ArMinEditFieldValueChanged(app, event)
            value = app.ArMinEditField.Value;
            app.ArMinSlider.Value = value;
            app.blobLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: ArMaxEditField
        function ArMaxEditFieldValueChanged(app, event)
            value = app.ArMaxEditField.Value;
            app.ArMaxSlider.Value = value;
            app.blobLimsCur(2) = value;
            updateImage(app);
        end

        % Value changed function: PiMinEditField
        function PiMinEditFieldValueChanged(app, event)
            value = app.PiMinEditField.Value;
            app.PiMinSlider.Value = value;
            app.blobLimsCur(3) = value;
            updateImage(app);
        end

        % Value changed function: PiMaxEditField
        function PiMaxEditFieldValueChanged(app, event)
            value = app.PiMaxEditField.Value;
            app.PiMaxSlider.Value = value;
            app.blobLimsCur(4) = value;
            updateImage(app);
        end

        % Value changed function: MajMinEditField
        function MajMinEditFieldValueChanged(app, event)
            value = app.MajMinEditField.Value;
            app.MajMinSlider.Value = value;
            app.blobLimsCur(5) = value;
            updateImage(app);
        end

        % Value changed function: MajMaxEditField
        function MajMaxEditFieldValueChanged(app, event)
            value = app.MajMaxEditField.Value;
            app.MajMaxSlider.Value = value;
            app.blobLimsCur(6) = value;
            updateImage(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %uiresume(app.UIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ArMinLabel = uilabel(app.UIFigure);
            app.ArMinLabel.Text = 'Ar. Min.';
            app.ArMinSlider = uislider(app.UIFigure);
            app.ArMinSlider.Limits = [0 1000];
            app.ArMinSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.ArMinSlider.ValueChangedFcn = createCallbackFcn(app, @ArMinSliderValueChanged, true);
            app.ArMinSlider.ValueChangingFcn = createCallbackFcn(app, @ArMinSliderValueChanging, true);
            app.ArMaxLabel = uilabel(app.UIFigure);
            app.ArMaxLabel.Text = 'Ar. Max.';
            app.ArMaxSlider = uislider(app.UIFigure);
            app.ArMaxSlider.Limits = [0 1000];
            app.ArMaxSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.ArMaxSlider.ValueChangedFcn = createCallbackFcn(app, @ArMaxSliderValueChanged, true);
            app.ArMaxSlider.ValueChangingFcn = createCallbackFcn(app, @ArMaxSliderValueChanging, true);
            app.PiMinLabel = uilabel(app.UIFigure);
            app.PiMinLabel.Text = 'Pi. Min.';
            app.PiMinSlider = uislider(app.UIFigure);
            app.PiMinSlider.Limits = [0 10];
            app.PiMinSlider.ValueChangedFcn = createCallbackFcn(app, @PiMinSliderValueChanged, true);
            app.PiMinSlider.ValueChangingFcn = createCallbackFcn(app, @PiMinSliderValueChanging, true);
            app.PiMaxLabel = uilabel(app.UIFigure);
            app.PiMaxLabel.Text = 'Pi. Max.';
            app.PiMaxSlider = uislider(app.UIFigure);
            app.PiMaxSlider.Limits = [0 10];
            app.PiMaxSlider.ValueChangedFcn = createCallbackFcn(app, @PiMaxSliderValueChanged, true);
            app.PiMaxSlider.ValueChangingFcn = createCallbackFcn(app, @PiMaxSliderValueChanging, true);
            app.MajMinLabel = uilabel(app.UIFigure);
            app.MajMinLabel.Text = 'Maj. Min.';
            app.MajMinSlider = uislider(app.UIFigure);
            app.MajMinSlider.ValueChangedFcn = createCallbackFcn(app, @MajMinSliderValueChanged, true);
            app.MajMinSlider.ValueChangingFcn = createCallbackFcn(app, @MajMinSliderValueChanging, true);
            app.MajMaxLabel = uilabel(app.UIFigure);
            app.MajMaxLabel.Text = 'Maj. Max.';
            app.MajMaxSlider = uislider(app.UIFigure);
            app.MajMaxSlider.ValueChangedFcn = createCallbackFcn(app, @MajMaxSliderValueChanged, true);
            app.MajMaxSlider.ValueChangingFcn = createCallbackFcn(app, @MajMaxSliderValueChanging, true);
            app.ResetArMinButton = uibutton(app.UIFigure, 'push');
            app.ResetArMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetArMinButtonPushed, true);
            app.ResetArMinButton.Text = 'Reset';
            app.ResetArMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetArMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetArMaxButtonPushed, true);
            app.ResetArMaxButton.Text = 'Reset';
            app.ResetPiMinButton = uibutton(app.UIFigure, 'push');
            app.ResetPiMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPiMinButtonPushed, true);
            app.ResetPiMinButton.Text = 'Reset';
            app.ResetPiMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetPiMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPiMaxButtonPushed, true);
            app.ResetPiMaxButton.Text = 'Reset';
            app.ResetMajMinButton = uibutton(app.UIFigure, 'push');
            app.ResetMajMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMajMinButtonPushed, true);
            app.ResetMajMinButton.Text = 'Reset';
            app.ResetMajMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetMajMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMajMaxButtonPushed, true);
            app.ResetMajMaxButton.Text = 'Reset';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ArMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArMinEditField.Limits = [0 1000];
            app.ArMinEditField.ValueDisplayFormat = '%.3f';
            app.ArMinEditField.ValueChangedFcn = createCallbackFcn(app, @ArMinEditFieldValueChanged, true);
            app.ArMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArMaxEditField.Limits = [0 1000];
            app.ArMaxEditField.ValueDisplayFormat = '%.3f';
            app.ArMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ArMaxEditFieldValueChanged, true);
            app.PiMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.PiMinEditField.Limits = [0 1000];
            app.PiMinEditField.ValueDisplayFormat = '%.3f';
            app.PiMinEditField.ValueChangedFcn = createCallbackFcn(app, @PiMinEditFieldValueChanged, true);
            app.PiMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PiMaxEditField.Limits = [0 1000];
            app.PiMaxEditField.ValueDisplayFormat = '%.3f';
            app.PiMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PiMaxEditFieldValueChanged, true);
            app.MajMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MajMinEditField.Limits = [0 1000];
            app.MajMinEditField.ValueDisplayFormat = '%.3f';
            app.MajMinEditField.ValueChangedFcn = createCallbackFcn(app, @MajMinEditFieldValueChanged, true);
            app.MajMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MajMaxEditField.Limits = [0 1000];
            app.MajMaxEditField.ValueDisplayFormat = '%.3f';
            app.MajMaxEditField.ValueChangedFcn = createCallbackFcn(app, @MajMaxEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Number'; 'X'; 'Y'; 'Area'; 'Pi'; 'Major'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = testBlobapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end