classdef testColorapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        HueMinSliderLabel  matlab.ui.control.Label
        HueMinSlider       matlab.ui.control.Slider
        HueMaxSliderLabel  matlab.ui.control.Label
        HueMaxSlider       matlab.ui.control.Slider
        SatMinSliderLabel  matlab.ui.control.Label
        SatMinSlider       matlab.ui.control.Slider
        SatMaxSliderLabel  matlab.ui.control.Label
        SatMaxSlider       matlab.ui.control.Slider
        ValMinSliderLabel  matlab.ui.control.Label
        ValMinSlider       matlab.ui.control.Slider
        ValMaxLabel        matlab.ui.control.Label
        ValMaxSlider       matlab.ui.control.Slider
        ResetHueMinButton  matlab.ui.control.Button
        ResetHueMaxButton  matlab.ui.control.Button
        ResetSatMinButton  matlab.ui.control.Button
        ResetSatMaxButton  matlab.ui.control.Button
        ResetValMinButton  matlab.ui.control.Button
        ResetValMaxButton  matlab.ui.control.Button
        ResetAllButton     matlab.ui.control.Button
        FinishButton       matlab.ui.control.Button
        HueMinEditField    matlab.ui.control.NumericEditField
        HueMaxEditField    matlab.ui.control.NumericEditField
        SatMinEditField    matlab.ui.control.NumericEditField
        SatMaxEditField    matlab.ui.control.NumericEditField
        ValMinEditField    matlab.ui.control.NumericEditField
        ValMaxEditField    matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        colLimsCur     double      % Current colLims
        colLimsOr      double      % Original colLims
        image                      % Image 
    end

    methods (Access = private)
    
        function updateImage(app)
            [~,maskedIm] = maskImage(app.image,app.colLimsCur);
            
            % Show Image
            imshow(maskedIm,'Parent',app.UIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, colLims)
            app.colLimsCur = colLims;
            app.colLimsOr = colLims;
            app.image = image;
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Set sliders
            app.HueMinSlider.Value = colLims(1);
            app.HueMaxSlider.Value = colLims(2);
            app.SatMinSlider.Value = colLims(3);
            app.SatMaxSlider.Value = colLims(4);
            app.ValMinSlider.Value = colLims(5);
            app.ValMaxSlider.Value = colLims(6);
            
            app.HueMinEditField.Value = colLims(1);
            app.HueMaxEditField.Value = colLims(2);
            app.SatMinEditField.Value = colLims(3);
            app.SatMaxEditField.Value = colLims(4);
            app.ValMinEditField.Value = colLims(5);
            app.ValMaxEditField.Value = colLims(6);
            
            updateImage(app);
            
        end

        % Value changed function: HueMinSlider
        function HueMinSliderValueChanged(app, event)
            value = app.HueMinSlider.Value;
            app.HueMinEditField.Value = value;
            app.colLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: SatMinSlider
        function SatMinSliderValueChanged(app, event)
            value = app.SatMinSlider.Value;
            app.SatMinEditField.Value = value;
            app.colLimsCur(3) = value;
            updateImage(app);
        end

        % Value changing function: SatMinSlider
        function SatMinSliderValueChanging(app, event)
            value = event.Value;
            app.SatMinEditField.Value = value;
            app.colLimsCur(3) = value;
            updateImage(app);
        end

        % Value changing function: HueMinSlider
        function HueMinSliderValueChanging(app, event)
            value = event.Value;
            app.HueMinEditField.Value = value;
            app.colLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: HueMaxSlider
        function HueMaxSliderValueChanged(app, event)
            value = app.HueMaxSlider.Value;
            app.HueMaxEditField.Value = value;
            app.colLimsCur(2) = value;
            updateImage(app);
        end

        % Value changing function: HueMaxSlider
        function HueMaxSliderValueChanging(app, event)
            value = event.Value;
            app.HueMaxEditField.Value = value;
            app.colLimsCur(2) = value;
            updateImage(app);
        end

        % Value changed function: SatMaxSlider
        function SatMaxSliderValueChanged(app, event)
            value = app.SatMaxSlider.Value;
            app.SatMaxEditField.Value = value;
            app.colLimsCur(4) = value;
            updateImage(app);
        end

        % Value changing function: SatMaxSlider
        function SatMaxSliderValueChanging(app, event)
            value = event.Value;
            app.SatMaxEditField.Value = value;
            app.colLimsCur(4) = value;
            updateImage(app);
        end

        % Value changed function: ValMinSlider
        function ValMinSliderValueChanged(app, event)
            value = app.ValMinSlider.Value;
            app.ValMinEditField.Value = value;
            app.colLimsCur(5) = value;
            updateImage(app);
        end

        % Value changing function: ValMinSlider
        function ValMinSliderValueChanging(app, event)
            value = event.Value;
            app.ValMinEditField.Value = value;
            app.colLimsCur(5) = value;
            updateImage(app);
        end

        % Value changed function: ValMaxSlider
        function ValMaxSliderValueChanged(app, event)
            value = app.ValMaxSlider.Value;
            app.ValMaxEditField.Value = value;
            app.colLimsCur(6) = value;
            updateImage(app);
        end

        % Value changing function: ValMaxSlider
        function ValMaxSliderValueChanging(app, event)
            value = event.Value;
            app.ValMaxEditField.Value = value;
            app.colLimsCur(6) = value;
            updateImage(app);
        end

        % Button pushed function: ResetHueMinButton
        function ResetHueMinButtonPushed(app, event)
            value = app.colLimsOr(1);
            app.colLimsCur(1) = value;
            app.HueMinSlider.Value = value;
            app.HueMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetHueMaxButton
        function ResetHueMaxButtonPushed(app, event)
            value = app.colLimsOr(2);
            app.colLimsCur(2) = value;
            app.HueMaxSlider.Value = value;
            app.HueMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetSatMinButton
        function ResetSatMinButtonPushed(app, event)
            value = app.colLimsOr(3);
            app.colLimsCur(3) = value;
            app.SatMinSlider.Value = value;
            app.SatMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetSatMaxButton
        function ResetSatMaxButtonPushed(app, event)
            value = app.colLimsOr(4);
            app.colLimsCur(4) = value;
            app.SatMaxSlider.Value = value;
            app.SatMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetValMinButton
        function ResetValMinButtonPushed(app, event)
            value = app.colLimsOr(5);
            app.colLimsCur(5) = value;
            app.ValMinSlider.Value = value;
            app.ValMinEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetValMaxButton
        function ResetValMaxButtonPushed(app, event)
            value = app.colLimsOr(6);
            app.colLimsCur(6) = value;
            app.ValMaxSlider.Value = value;
            app.ValMaxEditField.Value = value;
            updateImage(app);
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.colLimsCur = app.colLimsOr;
            app.HueMinSlider.Value = app.colLimsCur(1);
            app.HueMinEditField.Value = app.colLimsCur(1);
            app.HueMaxSlider.Value = app.colLimsCur(2);
            app.HueMaxEditField.Value = app.colLimsCur(2);
            
            app.SatMinSlider.Value = app.colLimsCur(3);
            app.SatMinEditField.Value = app.colLimsCur(3);
            app.SatMaxSlider.Value = app.colLimsCur(4);
            app.SatMaxEditField.Value = app.colLimsCur(4);
            
            app.ValMinSlider.Value = app.colLimsCur(5);
            app.ValMinEditField.Value = app.colLimsCur(5);
            app.ValMaxSlider.Value = app.colLimsCur(6);
            app.ValMaxEditField.Value = app.colLimsCur(6);
            updateImage(app);
        end

        % Value changed function: HueMinEditField
        function HueMinEditFieldValueChanged(app, event)
            value = app.HueMinEditField.Value;
            app.HueMinSlider.Value = value;
            app.colLimsCur(1) = value;
            updateImage(app);
        end

        % Value changed function: HueMaxEditField
        function HueMaxEditFieldValueChanged(app, event)
            value = app.HueMaxEditField.Value;
            app.HueMaxSlider.Value = value;
            app.colLimsCur(2) = value;
            updateImage(app);
        end

        % Value changed function: SatMinEditField
        function SatMinEditFieldValueChanged(app, event)
            value = app.SatMinEditField.Value;
            app.SatMinSlider.Value = value;
            app.colLimsCur(3) = value;
            updateImage(app);
        end

        % Value changed function: SatMaxEditField
        function SatMaxEditFieldValueChanged(app, event)
            value = app.SatMaxEditField.Value;
            app.SatMaxSlider.Value = value;
            app.colLimsCur(4) = value;
            updateImage(app);
        end

        % Value changed function: ValMinEditField
        function ValMinEditFieldValueChanged(app, event)
            value = app.ValMinEditField.Value;
            app.ValMinSlider.Value = value;
            app.colLimsCur(5) = value;
            updateImage(app);
        end

        % Value changed function: ValMaxEditField
        function ValMaxEditFieldValueChanged(app, event)
            value = app.ValMaxEditField.Value;
            app.ValMaxSlider.Value = value;
            app.colLimsCur(6) = value;
            updateImage(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %uiresume(app.UIFigure);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HueMinSliderLabel = uilabel(app.UIFigure);
            app.HueMinSliderLabel.Text = 'Hue Min.';
            app.HueMinSlider = uislider(app.UIFigure);
            app.HueMinSlider.Limits = [0 1];
            app.HueMinSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HueMinSlider.ValueChangedFcn = createCallbackFcn(app, @HueMinSliderValueChanged, true);
            app.HueMinSlider.ValueChangingFcn = createCallbackFcn(app, @HueMinSliderValueChanging, true);
            app.HueMaxSliderLabel = uilabel(app.UIFigure);
            app.HueMaxSliderLabel.Text = 'Hue Max.';
            app.HueMaxSlider = uislider(app.UIFigure);
            app.HueMaxSlider.Limits = [0 1];
            app.HueMaxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HueMaxSlider.ValueChangedFcn = createCallbackFcn(app, @HueMaxSliderValueChanged, true);
            app.HueMaxSlider.ValueChangingFcn = createCallbackFcn(app, @HueMaxSliderValueChanging, true);
            app.SatMinSliderLabel = uilabel(app.UIFigure);
            app.SatMinSliderLabel.Text = 'Sat. Min.';
            app.SatMinSlider = uislider(app.UIFigure);
            app.SatMinSlider.Limits = [0 1];
            app.SatMinSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SatMinSlider.ValueChangedFcn = createCallbackFcn(app, @SatMinSliderValueChanged, true);
            app.SatMinSlider.ValueChangingFcn = createCallbackFcn(app, @SatMinSliderValueChanging, true);
            app.SatMaxSliderLabel = uilabel(app.UIFigure);
            app.SatMaxSliderLabel.Text = 'Sat. Max.';
            app.SatMaxSlider = uislider(app.UIFigure);
            app.SatMaxSlider.Limits = [0 1];
            app.SatMaxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SatMaxSlider.ValueChangedFcn = createCallbackFcn(app, @SatMaxSliderValueChanged, true);
            app.SatMaxSlider.ValueChangingFcn = createCallbackFcn(app, @SatMaxSliderValueChanging, true);
            app.ValMinSliderLabel = uilabel(app.UIFigure);
            app.ValMinSliderLabel.Text = 'Val. Min.';
            app.ValMinSlider = uislider(app.UIFigure);
            app.ValMinSlider.Limits = [0 1];
            app.ValMinSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ValMinSlider.ValueChangedFcn = createCallbackFcn(app, @ValMinSliderValueChanged, true);
            app.ValMinSlider.ValueChangingFcn = createCallbackFcn(app, @ValMinSliderValueChanging, true);
            app.ValMaxLabel = uilabel(app.UIFigure);
            app.ValMaxLabel.Text = 'Val. Max.';
            app.ValMaxSlider = uislider(app.UIFigure);
            app.ValMaxSlider.Limits = [0 1];
            app.ValMaxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ValMaxSlider.ValueChangedFcn = createCallbackFcn(app, @ValMaxSliderValueChanged, true);
            app.ValMaxSlider.ValueChangingFcn = createCallbackFcn(app, @ValMaxSliderValueChanging, true);
            app.ResetHueMinButton = uibutton(app.UIFigure, 'push');
            app.ResetHueMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHueMinButtonPushed, true);
            app.ResetHueMinButton.Text = 'Reset';
            app.ResetHueMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetHueMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHueMaxButtonPushed, true);
            app.ResetHueMaxButton.Text = 'Reset';
            app.ResetSatMinButton = uibutton(app.UIFigure, 'push');
            app.ResetSatMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSatMinButtonPushed, true);
            app.ResetSatMinButton.Text = 'Reset';
            app.ResetSatMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetSatMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSatMaxButtonPushed, true);
            app.ResetSatMaxButton.Text = 'Reset';
            app.ResetValMinButton = uibutton(app.UIFigure, 'push');
            app.ResetValMinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValMinButtonPushed, true);
            app.ResetValMinButton.Text = 'Reset';
            app.ResetValMaxButton = uibutton(app.UIFigure, 'push');
            app.ResetValMaxButton.ButtonPushedFcn = createCallbackFcn(app, @ResetValMaxButtonPushed, true);
            app.ResetValMaxButton.Text = 'Reset';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.HueMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.HueMinEditField.Limits = [0 1];
            app.HueMinEditField.ValueDisplayFormat = '%.3f';
            app.HueMinEditField.ValueChangedFcn = createCallbackFcn(app, @HueMinEditFieldValueChanged, true);
            app.HueMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HueMaxEditField.Limits = [0 1];
            app.HueMaxEditField.ValueDisplayFormat = '%.3f';
            app.HueMaxEditField.ValueChangedFcn = createCallbackFcn(app, @HueMaxEditFieldValueChanged, true);
            app.SatMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatMinEditField.Limits = [0 1];
            app.SatMinEditField.ValueDisplayFormat = '%.3f';
            app.SatMinEditField.ValueChangedFcn = createCallbackFcn(app, @SatMinEditFieldValueChanged, true);
            app.SatMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatMaxEditField.Limits = [0 1];
            app.SatMaxEditField.ValueDisplayFormat = '%.3f';
            app.SatMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SatMaxEditFieldValueChanged, true);
            app.ValMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValMinEditField.Limits = [0 1];
            app.ValMinEditField.ValueDisplayFormat = '%.3f';
            app.ValMinEditField.ValueChangedFcn = createCallbackFcn(app, @ValMinEditFieldValueChanged, true);
            app.ValMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValMaxEditField.Limits = [0 1];
            app.ValMaxEditField.ValueDisplayFormat = '%.3f';
            app.ValMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ValMaxEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = testColorapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end