classdef testMatchapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CameraDropDown             matlab.ui.control.DropDown
        ConstraintsListBoxLabel    matlab.ui.control.Label
        ConstraintsListBox         matlab.ui.control.ListBox
        TabGroup                   matlab.ui.container.TabGroup
        PositionTab                matlab.ui.container.Tab
        ElementDropDownLabel       matlab.ui.control.Label
        ElementDropDown            matlab.ui.control.DropDown
        PositionXmmEditFieldLabel  matlab.ui.control.Label
        PositionXmmEditField       matlab.ui.control.NumericEditField
        PositionYmmEditFieldLabel  matlab.ui.control.Label
        PositionYmmEditField       matlab.ui.control.NumericEditField
        AddButton                  matlab.ui.control.Button
        WeightEditFieldLabel       matlab.ui.control.Label
        WeightEditField            matlab.ui.control.NumericEditField
        LineTab                    matlab.ui.container.Tab
        Element1DropDownLabel      matlab.ui.control.Label
        Element1DropDown           matlab.ui.control.DropDown
        AddButton_2                matlab.ui.control.Button
        Element2DropDownLabel      matlab.ui.control.Label
        Element2DropDown           matlab.ui.control.DropDown
        DirectionDropDownLabel     matlab.ui.control.Label
        DirectionDropDown          matlab.ui.control.DropDown
        MatchTab                   matlab.ui.container.Tab
        Element1DropDown_2Label    matlab.ui.control.Label
        Element1DropDown_2         matlab.ui.control.DropDown
        AddButton_3                matlab.ui.control.Button
        Element2DropDown_2Label    matlab.ui.control.Label
        Element2DropDown_2         matlab.ui.control.DropDown
        WeightEditField_2Label     matlab.ui.control.Label
        WeightEditField_2          matlab.ui.control.NumericEditField
        DeleteButton               matlab.ui.control.Button
        CalculateButton            matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UITable_2                  matlab.ui.control.Table
        InitialGuessLabel          matlab.ui.control.Label
        SolutionLabel              matlab.ui.control.Label
        RefineButton               matlab.ui.control.Button
        FinishButton               matlab.ui.control.Button
    end


    properties (Access = public)
        m             % Metadata
        image         % Image 
        constraints
        % Description
        constraintsStr % Description
        X % Description
        X0 % Description
    end

    methods (Access = private)
    
        function updateImage(app,camId)
            hold(app.UIAxes,'off');
            if(camId==0)
                plotCamPos(app);
            else
                camIm = getCamImage(app.image,app.m,camId);
                annIm = annotateImage(camIm,app.m.cam(camId));
                % Show Image
                imshow(annIm,'Parent',app.UIAxes);
            end
        end
        
        function popLists(app)
            
            app.ElementDropDown.Items = cellstr(getElementList(app.m,[],false));
            app.Element1DropDown.Items = cellstr(getElementList(app.m,[],true));
            app.Element1DropDown_2.Items = cellstr(getElementList(app.m,[],false));
            
            app.Element2DropDown.Items = cellstr(getElementList(app.m,[],true));
            changeSecondLists(app);
            
        end
        
        function changeSecondLists(app)
            El1_2Cam = app.Element1DropDown_2.Value(1);
            gE = getElementList(app.m,str2double(El1_2Cam),false);
            if(~isempty(gE))
                app.Element2DropDown_2.Items = cellstr(gE);
            else
                app.Element2DropDown_2.Items = {};
            end
        end
        
        function updateConList(app)
            app.ConstraintsListBox.Items = app.constraintsStr;
            
        end
        
        function plotCamPos(app)
            plot(app.UIAxes,0,0,'kx')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,[-500 500],[0 0],'k')
            plot(app.UIAxes,[0 0],[-300 300],'k')
            text(app.UIAxes,10,290,'Y+')
            text(app.UIAxes,480,10,'X+')
            text(app.UIAxes,10,-290,'Y-')
            text(app.UIAxes,-480,10,'X-')
            for camIdx = 1:app.m.nCams
                phi = app.X(camIdx,1);
                t = 1e2*app.X(camIdx,[2,3]).';
                R = [cos(phi) sin(phi); -sin(phi) cos(phi)];
                p = R.'*transformCamToCamWorld(app.m,[0,0;640 0; 640 480 ; 0 480; 0 0],camIdx).';
                app.UIAxes.ColorOrderIndex = camIdx;
                plot(app.UIAxes,t(1)+p(1,:), t(2)+p(2,:));


                app.UIAxes.ColorOrderIndex = camIdx;
                plot(app.UIAxes,t(1)+p(1,1),t(2)+p(2,1),'o');
                app.UIAxes.ColorOrderIndex = camIdx;
                plot(app.UIAxes,t(1)+p(1,2),t(2)+p(2,2),'*');
                app.UIAxes.ColorOrderIndex = camIdx;
                pCam = app.m.cam(camIdx).camPosWMean;
                p = R.'*pCam(1:2).';
                plot(app.UIAxes,t(1)+p(1,:), t(2)+p(2,:),'>');
                
                for mIdx = 1:app.m.cam(camIdx).mMarkers(1).n
                    marker = app.m.cam(camIdx).mMarkers(mIdx);
                    p = transformCamToCamWorld(app.m,[marker.x,marker.y],camIdx).';
                    app.UIAxes.ColorOrderIndex = camIdx;
                    plot(app.UIAxes,[t(1)+R(:,1).'*p],[t(2)+R(:,2).'*p],'s');
                end
                for mIdx = 1:app.m.cam(camIdx).pMarkers(1).n
                    marker = app.m.cam(camIdx).pMarkers(mIdx);
                    p = transformCamToCamWorld(app.m,[marker.x,marker.y],camIdx).';
                    app.UIAxes.ColorOrderIndex = camIdx;
                    plot(app.UIAxes,[t(1)+R(:,1).'*p],[t(2)+R(:,2).'*p],'d');
                end
                for mIdx = 1:app.m.cam(camIdx).checkers(1).n
                    checker = app.m.cam(camIdx).checkers(mIdx);
                    p = transformCamToCamWorld(app.m,[checker.imagePoints],camIdx).';
                    app.UIAxes.ColorOrderIndex = camIdx;
                    plot(app.UIAxes,[t(1)+R(:,1).'*p],[t(2)+R(:,2).'*p],'+');
                end
                
                
            end
            hold(app.UIAxes,'off');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, m, constr, constrStr, X)
            app.m = m;
            app.image = image;
            
            app.UITable.Data = X;
            app.UITable_2.Data = X;
            
            app.X0 = X;
            app.constraints = constr;
            app.constraintsStr = constrStr;
            app.X = X;


            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            camStr = cellstr([repmat('Camera ',m.nCams,1) num2str([1:m.nCams].')]);
            app.CameraDropDown.Items = [{'World 0'};camStr];
            app.CameraDropDown.Value = camStr{1};
            updateImage(app,1);
            popLists(app);
            
        end

        % Callback function
        function FinishButtonPushed(app, event)
            %uiresume(app.UIFigure);
            delete(app);
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            value = app.CameraDropDown.Value;
            updateImage(app,str2double(value(end)));
        end

        % Value changed function: ElementDropDown
        function ElementDropDownValueChanged(app, event)
            %value = app.ElementDropDown.Value;
            changeSecondLists(app);
        end

        % Value changed function: Element1DropDown
        function Element1DropDownValueChanged(app, event)
            %value = app.Element1DropDown.Value;
            changeSecondLists(app);
        end

        % Value changed function: Element1DropDown_2
        function Element1DropDown_2ValueChanged(app, event)
            %value = app.Element1DropDown_2.Value;
            changeSecondLists(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            El1 = app.ElementDropDown.Value;
            pX = app.PositionXmmEditField.Value;
            pY = app.PositionYmmEditField.Value;
            w = app.WeightEditField.Value;
            n = length(app.constraints)+1;
            app.constraints{end+1} = {'p',El1,pX,pY,w};
            app.constraintsStr{end+1} = [num2str(n) ' : ' El1 ' - X: ' num2str(pX) ', Y: ' num2str(pY) ', w: ' num2str(w)];
            updateConList(app);
                                       
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            El1 = app.Element1DropDown_2.Value;
            El2 = app.Element2DropDown_2.Value;
            w = app.WeightEditField_2.Value;
            n = length(app.constraints)+1;
            app.constraints{end+1} = {'m',El1,El2,w};
            app.constraintsStr{end+1} = [num2str(n) ' : ' El1 ' = ' El2 ', w: ' num2str(w)];
            updateConList(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.constraints = {app.constraints{1:end-1}};
            app.constraintsStr = {app.constraintsStr{1:end-1}};
            updateConList(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            X0t = app.UITable.Data;
            app.X = findCamPositions(app.m,app.constraints,X0t);
            app.UITable_2.Data = app.X;
            updateImage(app,0);
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            app.X = findCamPositions(app.m,app.constraints,app.X);
            app.UITable_2.Data = app.X;
            updateImage(app,0);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed2(app, event)
            %uiresume(app.UIFigure);
            delete(app);
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            El1 = app.Element1DropDown.Value;
            El2 = app.Element2DropDown.Value;
            dirS = app.DirectionDropDown.Value;
            w = app.WeightEditField_2.Value;
            n = length(app.constraints)+1;
            app.constraints{end+1} = {'l',El1,El2,dirS,w};
            app.constraintsStr{end+1} = [num2str(n) ' : ' El1 ' - ' El2 ' = ' dirS ', w: ' num2str(w)];
            updateConList(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.ConstraintsListBoxLabel = uilabel(app.UIFigure);
            app.ConstraintsListBoxLabel.Text = 'Constraints';
            app.ConstraintsListBox = uilistbox(app.UIFigure);
            app.ConstraintsListBox.Items = {};
            app.ConstraintsListBox.Value = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.ElementDropDownLabel = uilabel(app.PositionTab);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.PositionTab);
            app.ElementDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDropDownValueChanged, true);
            app.PositionXmmEditFieldLabel = uilabel(app.PositionTab);
            app.PositionXmmEditFieldLabel.Text = 'Position X [mm]';
            app.PositionXmmEditField = uieditfield(app.PositionTab, 'numeric');
            app.PositionXmmEditField.ValueDisplayFormat = '%.3f';
            app.PositionYmmEditFieldLabel = uilabel(app.PositionTab);
            app.PositionYmmEditFieldLabel.Text = 'Position Y [mm]';
            app.PositionYmmEditField = uieditfield(app.PositionTab, 'numeric');
            app.PositionYmmEditField.ValueDisplayFormat = '%.3f';
            app.AddButton = uibutton(app.PositionTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.WeightEditFieldLabel = uilabel(app.PositionTab);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.PositionTab, 'numeric');
            app.WeightEditField.ValueDisplayFormat = '%.3f';
            app.WeightEditField.Value = 1;
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.Element1DropDownLabel = uilabel(app.LineTab);
            app.Element1DropDownLabel.Text = 'Element 1';
            app.Element1DropDown = uidropdown(app.LineTab);
            app.Element1DropDown.ValueChangedFcn = createCallbackFcn(app, @Element1DropDownValueChanged, true);
            app.AddButton_2 = uibutton(app.LineTab, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.Element2DropDownLabel = uilabel(app.LineTab);
            app.Element2DropDownLabel.Text = 'Element 2';
            app.Element2DropDown = uidropdown(app.LineTab);
            app.DirectionDropDownLabel = uilabel(app.LineTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.LineTab);
            app.DirectionDropDown.Items = {'X+', 'X-', 'Y+', 'Y-'};
            app.DirectionDropDown.Value = 'X+';
            app.MatchTab = uitab(app.TabGroup);
            app.MatchTab.Title = 'Match';
            app.Element1DropDown_2Label = uilabel(app.MatchTab);
            app.Element1DropDown_2Label.Text = 'Element 1';
            app.Element1DropDown_2 = uidropdown(app.MatchTab);
            app.Element1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Element1DropDown_2ValueChanged, true);
            app.AddButton_3 = uibutton(app.MatchTab, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.Element2DropDown_2Label = uilabel(app.MatchTab);
            app.Element2DropDown_2Label.Text = 'Element 2';
            app.Element2DropDown_2 = uidropdown(app.MatchTab);
            app.WeightEditField_2Label = uilabel(app.MatchTab);
            app.WeightEditField_2Label.Text = 'Weight';
            app.WeightEditField_2 = uieditfield(app.MatchTab, 'numeric');
            app.WeightEditField_2.ValueDisplayFormat = '%.3f';
            app.WeightEditField_2.Value = 1;
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Phi'; 'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Phi'; 'X'; 'Y'};
            app.UITable_2.RowName = {};
            app.InitialGuessLabel = uilabel(app.UIFigure);
            app.InitialGuessLabel.Text = 'Initial Guess';
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = 'Solution';
            app.RefineButton = uibutton(app.UIFigure, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed2, true);
            app.FinishButton.Text = 'Finish';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testMatchapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end